classdef final_term < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Lmtrn4chsLabel                 matlab.ui.control.Label
        VuilngnhpsthpphnLabel          matlab.ui.control.Label
        KhongtnhtchphnEditField        matlab.ui.control.EditField
        KhongtnhtchphnLabel            matlab.ui.control.Label
        SNonconEditField               matlab.ui.control.EditField
        SNonconEditFieldLabel          matlab.ui.control.Label
        HmfxEditField                  matlab.ui.control.EditField
        HmfxEditFieldLabel             matlab.ui.control.Label
        KtqutnhhinthyLabel             matlab.ui.control.Label
        MNTHCHNHMATLABLabel            matlab.ui.control.Label
        NCUIK20200333NguynMinhTmLabel  matlab.ui.control.Label
        KtqutnhhinthyLabel_2           matlab.ui.control.Label
        KtqutnhhinthyLabel_3           matlab.ui.control.Label
        DrawButton                     matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DrawButton
        function clickedButton(app, event)
       app.UIFigure.Name = 'Đồ án cuối kỳ tính tích phân và vẽ đồ thị f(x)';
            function y = tichphanhinhthang(f,a,b,N)
                    h = (b-a) / N;
                    y = 0;
                    temp = (f(a) + f(b))* (h/2);
                    for i = 1:N-1
                        z = a + i*h;
                        y = f(z) + y;   
                    end
                    y = h*y + temp;
            end
            function y_simpson = tichphanSimpson(f,a,b,N)
                    h = (b - a)/N;
                    temp = (h/3)*(f(a)+f(b));
                    y_simpson = 0;
                    y1 = 0;
                    for i = 2: N-1
                        check = mod(i,2);
                        if check == 0
                            z = a + i*h;
                            f(z);
                            y_simpson = y_simpson + f(z);
                        elseif check ~= 0
                            z = a + i*h;
                            f(z);
                            y1 = y1 + f(z);
                        end
                    end
                    y_simpson = (y_simpson*2 + y1*4 )*h/3 + temp;
            end 
            function y_chinhxac = tichphanChinhxac(f,a,b)
                     y_chinhxac = integral(f,a,b);
            end
        try
            check_1  = 0; 
            check_temp = 0;
            check_temp1 = 0;
            check_temp2 = 0;
            check_temp3 = 0;
            fx = app.HmfxEditField.Value;
            fx = str2func(['@(x)', fx]);
            data = app.KhongtnhtchphnEditField.Value;
            N = num2str(app.SNonconEditField.Value);
            l = length(data);
            if ((data(1) ~='[') || (data(l) ~= ']'))
                msg = {'Lỗi xác định khoảng phân li nghiệm','Vui lòng nhập lại theo đúng cú pháp "[a;b]" '};
                msgbox(msg,'Error','error');
            else check_temp = 1;
            end
            format short;
            a_1 = str2num(data);
            temp_1 = a_1(1);
            t = 0;
            if (temp_1 == pi)
                t = 2;
            elseif temp_1 == -pi 
                t = 3;
            elseif  (temp_1/pi) == round((temp_1/pi),4) && (temp_1 ~=0)
                 t = numel(num2str(temp_1/pi));
                 t  = t+ 3;
            else             
                t = numel(num2str(temp_1));
            end
                t = t + 2;
            if ((data(t) ~= ";"))
                msg = {'Lỗi xác định khoảng tính tích phân','Vui lòng nhập lại theo đúng cú pháp "[a;b]" '};
                msgbox(msg,'Error','error'); 
            else check_temp1 = 1;
            end
                data_1 = str2num(data);
                n = length(data_1); 
                a =  data_1(1);
                b =  data_1(n);
                N = num2str(app.SNonconEditField.Value);
                N = str2num(N);  
                k = length(N);
            if ((N(1)<=0) || (k > 1) == 1)
                msg = {'Lỗi xác định số N đoạn con chia nhỏ', 'N chỉ là 1 số duy nhất','N không thể bằng 0 và không thể âm'};
  		        msgbox(msg,'Error','error'); 
            else check_temp2 = 1;   
            end

             if (a==b)
                msg = {'Lỗi xác định khoảng tính tích phân','a và b không thể bằng nhau'};
                msgbox(msg,'Error','error'); 
             else check_temp3 = 1;
             end
            check_1 = check_temp & check_temp1 & check_temp2 & check_temp3;
            if (check_1 == 1)
                format long; 
                results = tichphanhinhthang(fx,a,b,N);
                results_Simpson = tichphanSimpson(fx,a,b,N);
                results_chinhxac = tichphanChinhxac(fx,a,b);
                text =  sprintf('Phương pháp tích phân hình thang\n%.10f',results);
                app.KtqutnhhinthyLabel_2.Text = text ;
                text =  sprintf('Phương pháp tích phân Simpson \n%.10f',results_Simpson);
                app.KtqutnhhinthyLabel.Text = text ;
                text =  sprintf('Phương pháp tích phân Chính xác\n%.10f',results_chinhxac);
                app.KtqutnhhinthyLabel_3.Text = text ;
                y_divide = linspace(b,a,10);
                plot(app.UIAxes,y_divide,fx(y_divide),'LineWidth',3);
            else 
                text =  sprintf('Kết quả tính hiển thị ở đây');
                app.KtqutnhhinthyLabel_3.Text = text ;
                app.KtqutnhhinthyLabel_2.Text = text ;
                app.KtqutnhhinthyLabel.Text = text ;
                y_divide = linspace(0,1,10);
                
                plot(app.UIAxes,y_divide, 0);
                ylim(app.UIAxes,0:1);
         
            end
        catch error
            switch(error.identifier)
                case'MATLAB:m_incomplete_statement'
                    msgbox('Vui lòng nhập hàm f(x)','Error','error');
                case'MATLAB:mpower:notScalarAndSquareMatrix'
                        msg = {'Lỗi nhân ma trận!,','Để nhân từng phần tử, dùng dấu "." trước lệnh.  '};
                        msgbox(msg,'Error','error');  
                case'MATLAB:m_missing_operator'
                        msgbox('Sai định dạng hàm , vui lòng nhập lại','Error','error');
                case'MATLAB:UndefinedFunction'
                        msg = {'Lỗi không xác định hàm!,','Chỉ nhập hàm một biến và hàm cần theo biến x tức f(x)  '};
                        msgbox(msg,'Error','error'); 
                case'MATLAB:badsubscript'
                        if (isempty(data) && isempty(N))
                            msg = {'Vui lòng nhập khoảng tính tích phân','Vui lòng nhập vào số đoạn con N'};
                            msgbox(msg,'Error','error'); 
                        elseif isempty(data)
                            msg = {'Vui lòng nhập khoảng tính tích phân'};
                            msgbox(msg,'Error','error');
                        elseif isempty(N)
                            msgbox('Vui lòng nhập vào số đoạn con N','Error','error');
                        end
                case'MATLAB:dimagree'
                        msg = {'Lỗi xác định số N đoạn con','Vui lòng nhập số đoạn con và chỉ nhập một số N '};
                        msgbox(msg,'Error','error');                        
                otherwise msgbox({error.identifier,error.message},'Error','error');
            end
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Vẽ f(x) trên khoảng tính tích phân')
            xlabel(app.UIAxes, 'x trong khoảng tính tích phân đã được chia dều bằng linspcae')
            ylabel(app.UIAxes, 'f(x)')
            zlabel(app.UIAxes, 'Z')
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @clickedButton, true);
            app.DrawButton.Icon = fullfile(pathToMLAPP, 'kisspng-pencil-quill-computer-icons-pen-5abcbc7f0c2ec7.1788144315223184630499.jpg');
            app.DrawButton.Text = 'Draw';
            app.KtqutnhhinthyLabel_3 = uilabel(app.UIFigure);
            app.KtqutnhhinthyLabel_3.Text = 'Kết quả tính hiển thị ở đây';
            app.KtqutnhhinthyLabel_2 = uilabel(app.UIFigure);
            app.KtqutnhhinthyLabel_2.Text = 'Kết quả tính hiển thị ở đây';
            app.NCUIK20200333NguynMinhTmLabel = uilabel(app.UIFigure);
            app.NCUIK20200333NguynMinhTmLabel.Text = {'              ĐỒ ÁN CUỐI KÌ               '; '  20200333 - Nguyễn Minh Tâm'};
            app.MNTHCHNHMATLABLabel = uilabel(app.UIFigure);
            app.MNTHCHNHMATLABLabel.Text = 'MÔN THỰC HÀNH MATLAB';
            app.KtqutnhhinthyLabel = uilabel(app.UIFigure);
            app.KtqutnhhinthyLabel.Text = 'Kết quả tính hiển thị ở đây';
            app.HmfxEditFieldLabel = uilabel(app.UIFigure);
            app.HmfxEditFieldLabel.Text = 'Hàm f(x)';
            app.HmfxEditField = uieditfield(app.UIFigure, 'text');
            app.SNonconEditFieldLabel = uilabel(app.UIFigure);
            app.SNonconEditFieldLabel.Text = 'Số  N đoạn con ';
            app.SNonconEditField = uieditfield(app.UIFigure, 'text');
            app.KhongtnhtchphnLabel = uilabel(app.UIFigure);
            app.KhongtnhtchphnLabel.Text = 'Khoảng tính tích phân';
            app.KhongtnhtchphnEditField = uieditfield(app.UIFigure, 'text');
            app.VuilngnhpsthpphnLabel = uilabel(app.UIFigure);
            app.VuilngnhpsthpphnLabel.Text = 'Vui lòng nhập số thập phân';
            app.Lmtrn4chsLabel = uilabel(app.UIFigure);
            app.Lmtrn4chsLabel.Text = 'Làm tròn 4 chữ số ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final_term

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end